classdef KEMPO2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KEMPO2UIFigure               matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SimulatorTab                 matlab.ui.container.Tab
        LoadButton                   matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        jobnumberEditFieldLabel      matlab.ui.control.Label
        jobnumberEditField           matlab.ui.control.NumericEditField
        InputParametersPanel         matlab.ui.container.Panel
        GridTimeStepsPanel           matlab.ui.container.Panel
        dxEditFieldLabel             matlab.ui.control.Label
        dxEditField                  matlab.ui.control.NumericEditField
        dyEditFieldLabel             matlab.ui.control.Label
        dyEditField                  matlab.ui.control.NumericEditField
        dtEditFieldLabel             matlab.ui.control.Label
        dtEditField                  matlab.ui.control.NumericEditField
        ntimeEditFieldLabel          matlab.ui.control.Label
        ntimeEditField               matlab.ui.control.NumericEditField
        nyEditFieldLabel             matlab.ui.control.Label
        nyEditField                  matlab.ui.control.NumericEditField
        nxEditFieldLabel             matlab.ui.control.Label
        nxEditField                  matlab.ui.control.NumericEditField
        MagneticFieldPanel           matlab.ui.container.Panel
        cEditFieldLabel              matlab.ui.control.Label
        wcEditField                  matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        thetaEditField               matlab.ui.control.NumericEditField
        EditField_2Label             matlab.ui.control.Label
        phiEditField                 matlab.ui.control.NumericEditField
        PlasmaSpeciesPanel           matlab.ui.container.Panel
        nsSpinnerLabel               matlab.ui.control.Label
        nsSpinner                    matlab.ui.control.Spinner
        UITable                      matlab.ui.control.Table
        SpacePanel                   matlab.ui.container.Panel
        cvEditFieldLabel             matlab.ui.control.Label
        cvEditField                  matlab.ui.control.NumericEditField
        ExternalCurrentPanel         matlab.ui.container.Panel
        ajampEditFieldLabel          matlab.ui.control.Label
        ajampEditField               matlab.ui.control.NumericEditField
        jEditFieldLabel              matlab.ui.control.Label
        omegajEditField              matlab.ui.control.NumericEditField
        locationDropDownLabel        matlab.ui.control.Label
        locationDropDown             matlab.ui.control.DropDown
        SimulateButton               matlab.ui.control.Button
        SaveParametersduringtheentirejobPanel  matlab.ui.container.Panel
        NumberoftimesEditFieldLabel  matlab.ui.control.Label
        NumberoftimesEditField       matlab.ui.control.NumericEditField
        NotloadedyetLabel            matlab.ui.control.Label
        PanelOptions                 matlab.ui.container.Panel
        xyExyBCheckBox               matlab.ui.control.CheckBox
        kxkyEkxkyBCheckBox           matlab.ui.control.CheckBox
        wkxkyCheckBox                matlab.ui.control.CheckBox
        veloDistCheckBox             matlab.ui.control.CheckBox
        DropDown                     matlab.ui.control.DropDown
        EJCheckBox                   matlab.ui.control.CheckBox
        BJCheckBox                   matlab.ui.control.CheckBox
        SavetoEditFieldLabel         matlab.ui.control.Label
        SavetoEditField              matlab.ui.control.EditField
        ViewerTab                    matlab.ui.container.Tab
    end

    
    properties (Access = public)
        jobnumber
        EBnumber
        externalCurrentLocation
        ns
        
        allSpecies = {'Species 1'; 'Species 2'; 'Species 3'; 'Species 4'; 'Species 5'; 'Species 6'; 'Species 7'};
        
        fileWithoutDotM
        fileOnlyAlphabet
        startTime

        ctime
        ndskip
        
        savedParameterFile
        saveVideoFile
        videoPathSpecified
        
        courantCondMsg
        numSaveParams
        
        EJplot
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function constructer(app)
            clc;
            app.KEMPO2UIFigure.Visible = 'off';
            addpath('./params');
            
            load('./params/default.mat');
            
            %まずappオブジェクトに変数登録
            app.jobnumberEditField.Value = jobnumber;
            
            app.ns = size(PlasmaSpecies, 1);
%             app.PlasmaSpecies = PlasmaSpecies;
            
            app.fileWithoutDotM = 'Default0';
            app.fileOnlyAlphabet = 'Default';
            app.startTime = startTime;
            %GUIに表示
            app.jobnumberEditField.Value = jobnumber;  
            
            app.dxEditField.Value = Grid.dx;
            app.dyEditField.Value = Grid.dx;
            app.nxEditField.Value = Grid.nx;
            app.nyEditField.Value = Grid.ny;
            app.dtEditField.Value = TimeStep.dt;
            app.ntimeEditField.Value = TimeStep.ntime;
            
            app.wcEditField.Value = MagneticField.wc;
            app.thetaEditField.Value = MagneticField.theta;
            app.phiEditField.Value = MagneticField.phi;
            
            app.cvEditField.Value = cv;
             
            app.ajampEditField.Value = ExternalCurrent.ajamp;
            app.omegajEditField.Value = ExternalCurrent.omega;
            
            app.nsSpinner.Value = size(PlasmaSpecies, 1);
            
            app.ndskip = ndskip; 
 
            app.savedParameterFile = 'notSaved';
            app.videoPathSpecified = 0;
            app.SavetoEditField.Value = pwd;
            app.courantCondMsg = sprintf('The system has to satisfy Courant Condition; \n dx/dt > sqrt(2) * cv');
            app.NumberoftimesEditField.Value = 10;
            app.NotloadedyetLabel.Text = strcat('Default Parameter Loaded');
            
            app.xyExyBCheckBox.Value = 0; app.kxkyEkxkyBCheckBox.Value = 0;
            app.veloDistCheckBox.Value = 0; app.wkxkyCheckBox.Value = 0;
            app.EJCheckBox.Value = 1; app.BJCheckBox.Value = 1;
            
            app.DropDown.Visible = 'on'; app.DropDown.Value = 'Bz'; 
            app.EBnumber = 6; 

            app.UITable.ColumnEditable = [true true true true true true true];
            app.UITable.ColumnName = PlasmaSpecies.Properties.VariableNames;
            app.UITable.RowName = PlasmaSpecies.Properties.RowNames;
            app.UITable.Data = PlasmaSpecies;
            
            app.KEMPO2UIFigure.Visible = 'on';
        end

        % Button pushed function: LoadButton
        function loadpushed(app, event)
            addpath('./params')
            [filename,pathname] = uigetfile('./params/*.mat');
            if isequal(filename,0)
               disp('Load Cancelled');
            elseif strncmp('_jobnum', filename, 7)
                load(filename);
                
                continuousJob;
            else
                load(filename);
                
                %まずappオブジェクトに変数登録
                
                app.fileWithoutDotM = strtok(filename, '.');
                app.fileOnlyAlphabet = strtok(filename, '_');
                app.startTime = startTime;
                
                %GUIに表示
                app.jobnumberEditField.Value = jobnumber;  
                
                app.dxEditField.Value = Grid.dx;
                app.dyEditField.Value = Grid.dy;
                
                app.nxEditField.Value = Grid.nx;
                app.nyEditField.Value = Grid.ny;
                
                app.dtEditField.Value = TimeStep.dt;
                app.ntimeEditField.Value = TimeStep.ntime;
                
                app.wcEditField.Value = MagneticField.wc;
                app.thetaEditField.Value = MagneticField.theta;
                app.phiEditField.Value = MagneticField.phi;
                
                app.cvEditField.Value = cv;
                 
                app.ajampEditField.Value = ExternalCurrent.ajamp;
                app.omegajEditField.Value = ExternalCurrent.omega;
                
                app.UITable.Data = PlasmaSpecies;
                app.nsSpinner.Value = size(PlasmaSpecies, 1);
                app.ns = size(PlasmaSpecies, 1);
               
                app.ctime = ctime;
                app.ndskip = ndskip; 
                
                app.NotloadedyetLabel.Text = ['Loaded:' blanks(5) filename];
                app.NotloadedyetLabel.FontWeight = 'bold';
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            startKEMPO2;
        end

        % Value changed function: dxEditField
        function dxEditFieldValueChanged(app, event)
%             app.dx = app.dxEditField.Value;
            app.dyEditField.Value = app.dxEditField.Value;
            if app.dxEditField.Value / app.dtEditField.Value <= sqrt(2) * app.cvEditField.Value                
                uialert(app.KEMPO2UIFigure, app.courantCondMsg, 'Warning')
            end
        end

        % Value changed function: dyEditField
        function dyEditFieldValueChanged(app, event)
%             app.dy = app.dyEditField.Value;
            app.dxEditField.Value = app.dyEditField.Value;
            if app.dxEditField.Value / app.dtEditField.Value <= sqrt(2) * app.cvEditField.Value              
                uialert(app.KEMPO2UIFigure, app.courantCondMsg, 'Warning')
            end
        end

        % Value changed function: dtEditField
        function dtEditFieldValueChanged(app, event)
%             app.dt = app.dtEditField.Value;  
            if app.dxEditField.Value / app.dtEditField.Value <= sqrt(2) * app.cvEditField.Value               
                uialert(app.KEMPO2UIFigure, app.courantCondMsg, 'Warning')
            end
        end

        % Value changed function: nsSpinner
        function nsSpinnerValueChanged(app, event)
            if app.ns < app.nsSpinner.Value %大きくする場合
                qm = -1; wp = 2; np = 1048576; pch = 0; vd = 0; vPerp = 1; vPara = 1;
                T = table(qm, wp, np, pch, vd, vPerp, vPara);
           
                for i = 1:app.nsSpinner.Value - app.ns
                    app.UITable.Data = [app.UITable.Data; T];
                end                
            elseif app.ns > app.nsSpinner.Value %小さくする場合
                app.UITable.Data = app.UITable.Data([1:app.nsSpinner.Value], :);               
            end
            app.UITable.Data.Properties.RowNames = app.allSpecies(1:app.nsSpinner.Value);
            app.UITable.RowName = app.allSpecies(1:app.nsSpinner.Value);
            app.UITable.Data = app.UITable.Data; 
            app.ns = app.nsSpinner.Value;
        end

        % Value changed function: jobnumberEditField
        function jobnumberEditFieldValueChanged(app, event)
            app.jobnumber = app.jobnumberEditField.Value;            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            jobnumber = app.jobnumberEditField.Value;
            
            Grid.dx = app.dxEditField.Value;
            Grid.dy = app.dyEditField.Value;            
            Grid.nx = app.nxEditField.Value;
            Grid.ny = app.nyEditField.Value;
            
            TimeStep.dt = app.dtEditField.Value;
            TimeStep.ntime = app.ntimeEditField.Value;
            
            MagneticField.wc = app.wcEditField.Value;
            MagneticField.theta = app.thetaEditField.Value;
            MagneticField.phi = app.phiEditField.Value;
            
            cv = app.cvEditField.Value;
            
            ExternalCurrent.ajamp = app.ajampEditField.Value;
            ExternalCurrent.omega = app.omegajEditField.Value;
            
            ns = app.nsSpinner.Value;
           
            PlasmaSpecies = app.UITable.Data; 

            ctime = app.ctime;
            startTime = app.startTime;
            ndskip = app.ndskip; 
%             endTime = app.endTime;
            cd('./params')
            filter = {'*.mat'};
            [filename, pathname] = uiputfile(filter);
            if isequal(filename,0)
               disp('Save Cancelled');
            else
                save(filename, 'Grid', 'TimeStep', 'MagneticField', 'cv', 'ExternalCurrent', 'PlasmaSpecies', 'ctime', 'ndskip', 'jobnumber', 'startTime');
                app.savedParameterFile = filename;
                app.NotloadedyetLabel.Text = ['Saved:', blanks(5), filename];
            end
            cd ..
        end

        % Value changed function: SavetoEditField
        function SavetoEditFieldValueChanged(app, event)
            app.saveVideoFile = app.SavetoEditField.Value;
            app.videoPathSpecified = 1;
        end

        % Value changed function: NumberoftimesEditField
        function NumberoftimesEditFieldValueChanged(app, event)
            app.numSaveParams = app.NumberoftimesEditField.Value;   
            disp(app.numSaveParams)
        end

        % Value changed function: wkxkyCheckBox
        function wkxkyCheckBoxValueChanged(app, event)
            if app.wkxkyCheckBox.Value
                app.DropDown.Visible = 'on';
            else
                app.DropDown.Visible = 'off';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            switch app.DropDown.Value
              case 'Ex'
                app.EBnumber = 1;
              case 'Ey'
                app.EBnumber = 2;
              case 'Ez'
                app.EBnumber = 3;
              case 'Bx'
                app.EBnumber = 4;
              case 'By'
                app.EBnumber = 5;
              case 'Bz'
                app.EBnumber = 6;
            end            
        end

        % Value changed function: cvEditField
        function cvEditFieldValueChanged(app, event)
            if app.dxEditField.Value / app.dtEditField.Value <= sqrt(2) * app.cvEditField.Value               
                uialert(app.KEMPO2UIFigure, app.courantCondMsg, 'Warning')
            end
        end

        % Value changed function: locationDropDown
        function locationDropDownValueChanged(app, event)
            switch app.locationDropDown.Value
                case 'central point'
                    app.externalCurrentLocation = 0;
                case 'center line along y_axis'
                    app.externalCurrentLocation = 1;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KEMPO2UIFigure = uifigure('Visible', 'off');
            app.KEMPO2UIFigure.Name = 'KEMPO 2';
            app.TabGroup = uitabgroup(app.KEMPO2UIFigure);
            app.SimulatorTab = uitab(app.TabGroup);
            app.SimulatorTab.Title = 'Simulator';
            app.LoadButton = uibutton(app.SimulatorTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadpushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.SimulatorTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.jobnumberEditFieldLabel = uilabel(app.SimulatorTab);
            app.jobnumberEditFieldLabel.Text = 'jobnumber';
            app.jobnumberEditField = uieditfield(app.SimulatorTab, 'numeric');
            app.jobnumberEditField.ValueChangedFcn = createCallbackFcn(app, @jobnumberEditFieldValueChanged, true);
            app.InputParametersPanel = uipanel(app.SimulatorTab);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.GridTimeStepsPanel = uipanel(app.InputParametersPanel);
            app.GridTimeStepsPanel.Title = 'Grid & Time-Steps';
            app.dxEditFieldLabel = uilabel(app.GridTimeStepsPanel);
            app.dxEditFieldLabel.Text = 'dx';
            app.dxEditField = uieditfield(app.GridTimeStepsPanel, 'numeric');
            app.dxEditField.ValueChangedFcn = createCallbackFcn(app, @dxEditFieldValueChanged, true);
            app.dyEditFieldLabel = uilabel(app.GridTimeStepsPanel);
            app.dyEditFieldLabel.Text = 'dy';
            app.dyEditField = uieditfield(app.GridTimeStepsPanel, 'numeric');
            app.dyEditField.ValueChangedFcn = createCallbackFcn(app, @dyEditFieldValueChanged, true);
            app.dtEditFieldLabel = uilabel(app.GridTimeStepsPanel);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.GridTimeStepsPanel, 'numeric');
            app.dtEditField.ValueChangedFcn = createCallbackFcn(app, @dtEditFieldValueChanged, true);
            app.ntimeEditFieldLabel = uilabel(app.GridTimeStepsPanel);
            app.ntimeEditFieldLabel.Text = 'ntime';
            app.ntimeEditField = uieditfield(app.GridTimeStepsPanel, 'numeric');
            app.nyEditFieldLabel = uilabel(app.GridTimeStepsPanel);
            app.nyEditFieldLabel.Text = 'ny';
            app.nyEditField = uieditfield(app.GridTimeStepsPanel, 'numeric');
            app.nxEditFieldLabel = uilabel(app.GridTimeStepsPanel);
            app.nxEditFieldLabel.Text = 'nx';
            app.nxEditField = uieditfield(app.GridTimeStepsPanel, 'numeric');
            app.MagneticFieldPanel = uipanel(app.InputParametersPanel);
            app.MagneticFieldPanel.Title = 'Magnetic Field';
            app.cEditFieldLabel = uilabel(app.MagneticFieldPanel);
            app.cEditFieldLabel.Tooltip = {'Cyclotron frequency of Species 1'};
            app.cEditFieldLabel.Text = 'ωc';
            app.wcEditField = uieditfield(app.MagneticFieldPanel, 'numeric');
            app.EditFieldLabel = uilabel(app.MagneticFieldPanel);
            app.EditFieldLabel.Text = 'θ';
            app.thetaEditField = uieditfield(app.MagneticFieldPanel, 'numeric');
            app.EditField_2Label = uilabel(app.MagneticFieldPanel);
            app.EditField_2Label.Text = 'φ';
            app.phiEditField = uieditfield(app.MagneticFieldPanel, 'numeric');
            app.PlasmaSpeciesPanel = uipanel(app.InputParametersPanel);
            app.PlasmaSpeciesPanel.Tooltip = {''};
            app.PlasmaSpeciesPanel.Title = 'Plasma Species';
            app.nsSpinnerLabel = uilabel(app.PlasmaSpeciesPanel);
            app.nsSpinnerLabel.Text = 'ns';
            app.nsSpinner = uispinner(app.PlasmaSpeciesPanel);
            app.nsSpinner.Limits = [1 7];
            app.nsSpinner.ValueChangedFcn = createCallbackFcn(app, @nsSpinnerValueChanged, true);
            app.nsSpinner.Value = 1;
            app.UITable = uitable(app.PlasmaSpeciesPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Tooltip = {''};
            app.SpacePanel = uipanel(app.InputParametersPanel);
            app.SpacePanel.Title = 'Space';
            app.cvEditFieldLabel = uilabel(app.SpacePanel);
            app.cvEditFieldLabel.Text = 'cv';
            app.cvEditField = uieditfield(app.SpacePanel, 'numeric');
            app.cvEditField.ValueChangedFcn = createCallbackFcn(app, @cvEditFieldValueChanged, true);
            app.ExternalCurrentPanel = uipanel(app.InputParametersPanel);
            app.ExternalCurrentPanel.Title = 'External Current';
            app.ajampEditFieldLabel = uilabel(app.ExternalCurrentPanel);
            app.ajampEditFieldLabel.Text = 'ajamp';
            app.ajampEditField = uieditfield(app.ExternalCurrentPanel, 'numeric');
            app.jEditFieldLabel = uilabel(app.ExternalCurrentPanel);
            app.jEditFieldLabel.Text = 'ωj';
            app.omegajEditField = uieditfield(app.ExternalCurrentPanel, 'numeric');
            app.locationDropDownLabel = uilabel(app.ExternalCurrentPanel);
            app.locationDropDownLabel.Text = 'location';
            app.locationDropDown = uidropdown(app.ExternalCurrentPanel);
            app.locationDropDown.Items = {'central point', 'center line along y_axis'};
            app.locationDropDown.ValueChangedFcn = createCallbackFcn(app, @locationDropDownValueChanged, true);
            app.locationDropDown.Value = 'central point';
            app.SimulateButton = uibutton(app.SimulatorTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = {'Simulate'; ''};
            app.SaveParametersduringtheentirejobPanel = uipanel(app.SimulatorTab);
            app.SaveParametersduringtheentirejobPanel.Title = 'Save Parameters during the entire job';
            app.NumberoftimesEditFieldLabel = uilabel(app.SaveParametersduringtheentirejobPanel);
            app.NumberoftimesEditFieldLabel.Text = 'Number of times';
            app.NumberoftimesEditField = uieditfield(app.SaveParametersduringtheentirejobPanel, 'numeric');
            app.NumberoftimesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoftimesEditFieldValueChanged, true);
            app.NotloadedyetLabel = uilabel(app.SimulatorTab);
            app.NotloadedyetLabel.Text = 'Not loaded yet';
            app.PanelOptions = uipanel(app.SimulatorTab);
            app.PanelOptions.Title = 'Panel Options';
            app.xyExyBCheckBox = uicheckbox(app.PanelOptions);
            app.xyExyBCheckBox.Text = 'x-y-E, x-y-B';
            app.kxkyEkxkyBCheckBox = uicheckbox(app.PanelOptions);
            app.kxkyEkxkyBCheckBox.Text = 'kx-ky-E, kx-ky-B';
            app.wkxkyCheckBox = uicheckbox(app.PanelOptions);
            app.wkxkyCheckBox.ValueChangedFcn = createCallbackFcn(app, @wkxkyCheckBoxValueChanged, true);
            app.wkxkyCheckBox.Text = 'kx - ky - ω';
            app.veloDistCheckBox = uicheckbox(app.PanelOptions);
            app.veloDistCheckBox.Text = 'velocity Distribution';
            app.DropDown = uidropdown(app.PanelOptions);
            app.DropDown.Items = {'Ex', 'Ey', 'Ez', 'Bx', 'By', 'Bz'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Ex';
            app.EJCheckBox = uicheckbox(app.PanelOptions);
            app.EJCheckBox.Text = 'E·J';
            app.BJCheckBox = uicheckbox(app.PanelOptions);
            app.BJCheckBox.Text = 'B·J';
            app.SavetoEditFieldLabel = uilabel(app.SimulatorTab);
            app.SavetoEditFieldLabel.Text = 'Save to';
            app.SavetoEditField = uieditfield(app.SimulatorTab, 'text');
            app.SavetoEditField.ValueChangedFcn = createCallbackFcn(app, @SavetoEditFieldValueChanged, true);
            app.ViewerTab = uitab(app.TabGroup);
            app.ViewerTab.Title = 'Viewer';
            % Show the figure after all components are created
            app.KEMPO2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KEMPO2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KEMPO2UIFigure)

            % Execute the startup function
            runStartupFcn(app, @constructer)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KEMPO2UIFigure)
        end
    end
end